classdef filterandfit2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        SelectDataButton          matlab.ui.control.Button
        PlotButton                matlab.ui.control.Button
        SetRise1                  matlab.ui.control.Button
        SetRise2                  matlab.ui.control.Button
        SetFall1                  matlab.ui.control.Button
        SetFall2                  matlab.ui.control.Button
        Filter                    matlab.ui.control.Button
        InterpolateandPlotButton  matlab.ui.control.Button
        FitButton                 matlab.ui.control.Button
        FindPointsButton          matlab.ui.control.Button
        CellNameEditFieldLabel    matlab.ui.control.Label
        CellNum                   matlab.ui.control.EditField
        PointsSliderLabel         matlab.ui.control.Label
        PointsSlider              matlab.ui.control.Slider
        Rise1Label                matlab.ui.control.Label
        rise1                     matlab.ui.control.TextArea
        Rise2Label                matlab.ui.control.Label
        rise2                     matlab.ui.control.TextArea
        Fall1Label                matlab.ui.control.Label
        fall1                     matlab.ui.control.TextArea
        Fall2Label                matlab.ui.control.Label
        fall2                     matlab.ui.control.TextArea
        E0TextAreaLabel           matlab.ui.control.Label
        E0                        matlab.ui.control.TextArea
        E1TextAreaLabel           matlab.ui.control.Label
        E1                        matlab.ui.control.TextArea
        Tau0TextAreaLabel         matlab.ui.control.Label
        Tau0                      matlab.ui.control.TextArea
        Tau1TextArea_2Label       matlab.ui.control.Label
        Tau1                      matlab.ui.control.TextArea
        ErrorTextAreaLabel        matlab.ui.control.Label
        Error                     matlab.ui.control.TextArea
        PercentageRecoveryTextAreaLabel  matlab.ui.control.Label
        PRB                       matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        cellnum % cell loaded
        Struct %loaded data
        compliance % cell compliance
        time % cell time
        R1 % start of rising edge
        R2 % end of rising edge
        F1 %start of falling edge
        F2 %end of falling edge
        inter_butter %interpolated compliance
        inter_time %interpolated time
    end
    
    properties (Access = private)
        point % slider point
        pex % point exists
        ids %stored index of point
        butter_filter %filtered compliance
        unified_time %filtered time
        bfit %filtered compliance without falling edge
        tfit %filtered time without falling edge
    end
    
    methods (Access = private)
        
        function [jumps, falls, jt, ft] = interpolate(app, time, compliance)
            size_comp = size(compliance);
            jump = zeros(2,2);
            difference = 5e2;
            for i=2:size_comp(1,2)
                if compliance(i)-compliance(i-1) > difference
                    jump(1,1) = compliance(i-1);
                    jump(1,2) = compliance(i);
                    jump(2,1) = time(i-1);
                    jump(2,2) = time(i);
                    break
                end
            end
            
            % Create data points for rising edge
            jump_points = zeros(50,2);
            for i=1:50  
                jump_points(i,1) = jump(2,1) + i*(jump(2,2) - jump(2,1))/50;
                jump_points(i,2) = jump(1,1) + i*(jump(1,2) - jump(1,1))/50;
            end
            
            % Create data points for falling edge
            fall = zeros(2,2);
            for i=2:size_comp(1,2)
                if compliance(i-1)-compliance(i) > difference
                    fall(1,1) = compliance(i-1);
                    fall(1,2) = compliance(i);
                    fall(2,1) = time(i-1);
                    fall(2,2) = time(i);
                    break
                end
            end
            fall_points = zeros(50,2);
            for i=1:50
                fall_points(i,1) = fall(2,1) + i*(fall(2,2) - fall(2,1))/50;
                fall_points(i,2) = fall(1,1) - i*(fall(1,1) - fall(1,2))/50;
            end
            
            jumps = jump_points;
            falls = fall_points;
            jt = jump; 
            ft = fall;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            %load selected file
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                disp('User selected cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            
            app.Struct = load(fullfile(path,file));
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            hold(app.UIAxes, 'off');
            app.cellnum = app.CellNum;
            
            num = app.cellnum;
            struct = app.Struct;
            Cell = struct.StructSum.(num.Value);
            
            %processing data
            radius = 14e-6;
            force = 6e-9;
            csa = 2*pi*radius;
            displac = Cell.r_nm * 1e-9;
            app.time = Cell.time;
            
            strain = displac/radius;
            stress = force/csa;
            
            app.compliance = strain/stress;
            
            plot(app.UIAxes,app.time,app.compliance);
            app.PointsSlider.Limits = [min(app.time), max(app.time)];
        end

        % Value changing function: PointsSlider
        function PointsSliderValueChanging(app, event)
            changingValue = event.Value;
            if exist('app.pex', 'var') == 1
                delete app.point;
                app.pex = 0;
            end
            
            %round to nearest .02
            t = floor(changingValue) + ceil( (changingValue-floor(changingValue))/0.02) * 0.02;
            
            [~, idx] = find(app.time == t);
            hold(app.UIAxes, 'on');
            app.point = plot(app.UIAxes, app.time(idx), app.compliance(idx), 'r+');
            app.pex = 1;
        end

        % Value changed function: PointsSlider
        function PointsSliderValueChanged(app, event)
            value = app.PointsSlider.Value;
            t = floor(value) + ceil( (value-floor(value))/0.02) * 0.02;
            
            [~, app.ids] = find(app.time == t); 
        end

        % Button pushed function: SetRise1
        function SetRise1ButtonPushed(app, event)
            %find time and compliance of idx
            t = app.time(app.ids);
            c = app.compliance(app.ids);
            
            %start of rising pulse
            app.R1 = round((50*(round(t/0.02)/50))+1);

            plot(app.UIAxes, t, c, "ok");
            app.rise1.Value = {num2str(t), num2str(c)};
        end

        % Button pushed function: SetRise2
        function SetRise2ButtonPushed(app, event)
            %find time and compliance of idx
            t = app.time(app.ids);
            c = app.compliance(app.ids);
            
            %end of rising pulse
            app.R2 = round((50*(round(t/0.02)/50))+1);
            
            plot(app.UIAxes, t, c, "ok");
            app.rise2.Value = {num2str(t), num2str(c)};
        end

        % Button pushed function: SetFall1
        function SetFall1ButtonPushed(app, event)
            %find time and compliance of idx
            t = app.time(app.ids);
            c = app.compliance(app.ids);
            
            %start of falling pulse
            app.F1 = round((50*(round(t/0.02)/50))+1);
            
            plot(app.UIAxes, t, c, "ok");
            app.fall1.Value = {num2str(t), num2str(c)};
        end

        % Button pushed function: SetFall2
        function SetFall2ButtonPushed(app, event)
            %find time and compliance of idx
            t = app.time(app.ids);
            c = app.compliance(app.ids);
            
            %end of falling pulse
            app.F2 = round((50*(round(t/0.02)/50))+1);
            
            plot(app.UIAxes, t, c, "ok");
            app.fall2.Value = {num2str(t), num2str(c)};
        end

        % Button pushed function: Filter
        function FilterButtonPushed(app, event)
            fs = 50; %sampling rate of 50 Hz
            fc = 0.75; %cut-off frequency of 0.75
            
            % Designs a low pass IIR (infinite impulse response) filter with the previously inputted frequency values
            d1 = designfilt('lowpassiir', 'FilterOrder', 2,...
                'HalfPowerFrequency', fc,'DesignMethod', 'butter',...
                'SampleRate',fs); %Low pass Butterworth Filter design

            %transpose data to 0
            transposed_pulse = app.compliance(app.R2:app.F1) - app.compliance(app.R2);
            transposed_disp = app.compliance(app.F2:app.F2+100) - mean(app.compliance(app.F2:app.F2+10));
            
            %filter in sections
            pre_buttered = filtfilt(d1, app.compliance(1:app.R1));
            peri_buttered = filtfilt(d1, transposed_pulse(1:end)) + app.compliance(app.R2);
            post_buttered = filtfilt(d1, transposed_disp(1:length(app.F2:app.F2+100))) + mean(app.compliance(app.F2:app.F2+10));
            
            %important time points in curve
            pre_pulse_times = 0:1/fs:1;
            rise_gap_time = (app.R2 - app.R1)/fs;            
            pulse_time_start = 1 + rise_gap_time;
            pulse_time_end = ((app.F1 - app.R2)/fs) + pulse_time_start;
            fall_gap_interval = (app.F2 - app.F1)/fs;
            post_pulse_time_start = pulse_time_end + fall_gap_interval;
            post_pulse_time_end = post_pulse_time_start + 2;
            pulse_times = pulse_time_start:1 / fs:pulse_time_end;
            post_pulse_times = post_pulse_time_start:1 / fs:post_pulse_time_end;
            
            %remove any remenant magnetism (assume constant compliance)
            b = mean(pre_buttered(end-50:end));
            remMag1 = b.*ones(1,length(pre_pulse_times));
            remMag2 = b.*ones(1,length(pulse_times));
            remMag3 = b.*ones(1,length(post_pulse_times));
          
            %output sectioned filtered compliances
            butterCompliance1 = pre_buttered(end-50:end) - remMag1';
            butterCompliance2 = peri_buttered - remMag2';
            butterCompliance3 = post_buttered - remMag3';
            
            %output sectioned times
            butterTime1 = pre_pulse_times;
            butterTime2 = pulse_times;
            butterTime3 = post_pulse_times;
           
            %plot of unified filter pulse
            app.butter_filter = [butterCompliance1', butterCompliance2', butterCompliance3'];
            app.unified_time = [butterTime1, butterTime2, butterTime3];
            shiftedRawTime = app.time(app.R1-fs*1:app.F2+fs*2);
            shiftedRawCompliance = app.compliance(app.R1-fs*1:app.F2+fs*2) - mean(app.compliance(app.R1-fs*1:app.R1-fs*1+10));
            
            %plot the data
            hold(app.UIAxes, 'off');
            t(1) = plot(app.UIAxes,shiftedRawTime-app.time(app.R1-fs*1),shiftedRawCompliance);
            hold(app.UIAxes, 'on');
            t(2) = plot(app.UIAxes,app.unified_time, app.butter_filter, 'g+-');
            
            %for fitting
            app.bfit = [butterCompliance1', butterCompliance2'];
            app.tfit = [butterTime1, butterTime2];
            
            %recovery time
            rise_edge_start = [app.R1 app.compliance(app.R1)];
            rise_edge_end = [app.R2 app.compliance(app.R2)];
            fall_edge_start = [app.F1 app.compliance(app.F1)];
            fall_edge_end = [app.F2 app.compliance(app.F2)];
            recovery = (fall_edge_start(2) - rise_edge_start(2)) - (fall_edge_start(2) - fall_edge_end(2));
            percent_recovery = (recovery/ (fall_edge_start(2)-rise_edge_start(2)) )*100;
            
            app.PRB.Value = {num2str(percent_recovery)};
        end

        % Button pushed function: InterpolateandPlotButton
        function InterpolateandPlotButtonPushed(app, event)
            time_matrix = app.unified_time;
            compliance_matrix = app.butter_filter;

            [jump, fall, t1, t2] = interpolate(app, time_matrix, compliance_matrix);
            
            jump_times = jump(:,1);
            fall_times = fall(:,1);
            interpol_time = [time_matrix(1:t1(2,1)) jump_times' time_matrix(t1(2,2):t2(2,1)) fall_times' time_matrix(t2(2,2):end)];
            jump_compliances = jump(:,2);
            fall_compliances = fall(:,2);
            interpol_compliance = [compliance_matrix(1:t1(2,1)) jump_compliances' compliance_matrix(t1(2,2):t2(2,1)) fall_compliances' compliance_matrix(t2(2,2):end)];
            
            hold(app.UIAxes, 'off');
            plot(app.UIAxes, interpol_time,interpol_compliance,'o'); 
            
            %save interpolated data
            app.inter_butter = interpol_compliance;
            app.inter_time = interpol_time;
            
            %display in matlab workspace
            assignin('base','time',app.inter_time);
            assignin('base','compliance',app.inter_butter);
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            ydata = app.bfit; 
            xdata = app.tfit;
            x0 = [4000 0.002 0.2 0.002]; % initial guess for parameter values
            
            lb = [0 0 0 0];
            ub = [Inf 100 100 100];
            
            mse = 1000000;
            
            % p = [E0 E1 tau0 tau1]
            fun = @(p,xdata) (1./(p(1))) + (1./(p(2))).*(1-exp((-xdata.*p(2))./(p(4)))) + xdata./p(3);
            
            options = optimoptions('lsqcurvefit','FunctionTolerance',1e-8,'MaxFunctionEvaluations',1e5);
            [params] = lsqcurvefit(fun,x0,xdata,ydata,lb,ub,options);
            
            % build regressed curve
            regComp = fun(params,xdata);
            
            if (mean(abs(regComp-ydata))<mse)
                 mse = mean(abs(regComp-ydata));
                 e0 = params(1);
                 e1 = params(2);
                 tau0 = params(3);
                 tau1 = params(4);
                 goodRegComp = regComp;
            end
            
            regComp_posint = goodRegComp >= 0;
            goodRegComp = goodRegComp(regComp_posint);
            timeRC = xdata(regComp_posint);
            
            % plot the result of the fit
            hold(app.UIAxes,'off')
            plot(app.UIAxes, app.unified_time,app.butter_filter, 'b');
            hold(app.UIAxes,'on');
            plot(app.UIAxes, timeRC,goodRegComp,'r');
            
            %print parameters
            app.E0.Value = {num2str(e0)};
            app.E1.Value = {num2str(e1)};
            app.Tau0.Value = {num2str(tau0)};
            app.Tau1.Value = {num2str(tau1)};
            app.Error.Value = {num2str(mse)};
            
            %output parameteres in workspace
            assignin('base','E0',e0);
            assignin('base','E1',e1);
            assignin('base','Tau0',tau0);
            assignin('base','Tau1',tau1);
            assignin('base','error',mse);
        end

        % Button pushed function: FindPointsButton
        function FindPointsButtonPushed(app, event)
            time_matrix = app.time;
            compliance_matrix = app.compliance;
            
            size_comp = size(compliance_matrix);
            jump = zeros(2,2);
            difference = 100;
            for i=2:size_comp(1,2)
                if compliance_matrix(i)-compliance_matrix(i-1) > difference
                    jump(1,1) = compliance_matrix(i-1);
                    jump(1,2) = compliance_matrix(i);
                    jump(2,1) = time_matrix(i-1);
                    jump(2,2) = time_matrix(i);
                    break
                end
            end
            
            app.R1 = jump(2,1);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, jump(2,1), jump(1,1), "ok");
            app.rise1.Value = {num2str(jump(2,1)), num2str(jump(1,1))};
            
            app.R2 = jump(2,2);
            plot(app.UIAxes, jump(2,2), jump(1,2), "ok");
            app.rise2.Value = {num2str(jump(2,2)), num2str(jump(1,2))};
            
            fall = zeros(2,2);
            for i=2:size_comp(1,2)
                if compliance_matrix(i-1)-compliance_matrix(i) > difference
                    fall(1,1) = compliance_matrix(i-1);
                    fall(1,2) = compliance_matrix(i);
                    fall(2,1) = time_matrix(i-1);
                    fall(2,2) = time_matrix(i);
                    break
                end
            end
            
            app.F1 = fall(2,1);
            plot(app.UIAxes, fall(2,1), fall(1,1), "ok");
            app.fall1.Value = {num2str(fall(2,1)), num2str(fall(1,1))};
            
            app.F2 = fall(2,2);
            plot(app.UIAxes, fall(2,2), fall(1,2), "ok");
            app.fall2.Value = {num2str(fall(2,2)), num2str(fall(1,2))};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Compliance')
            app.SelectDataButton = uibutton(app.UIFigure, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SetRise1 = uibutton(app.UIFigure, 'push');
            app.SetRise1.ButtonPushedFcn = createCallbackFcn(app, @SetRise1ButtonPushed, true);
            app.SetRise1.Text = 'Set Rise 1';
            app.SetRise2 = uibutton(app.UIFigure, 'push');
            app.SetRise2.ButtonPushedFcn = createCallbackFcn(app, @SetRise2ButtonPushed, true);
            app.SetRise2.Text = 'Set Rise 2';
            app.SetFall1 = uibutton(app.UIFigure, 'push');
            app.SetFall1.ButtonPushedFcn = createCallbackFcn(app, @SetFall1ButtonPushed, true);
            app.SetFall1.Text = 'Set Fall 1';
            app.SetFall2 = uibutton(app.UIFigure, 'push');
            app.SetFall2.ButtonPushedFcn = createCallbackFcn(app, @SetFall2ButtonPushed, true);
            app.SetFall2.Text = 'Set Fall 2';
            app.Filter = uibutton(app.UIFigure, 'push');
            app.Filter.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.Filter.Text = 'Filter';
            app.InterpolateandPlotButton = uibutton(app.UIFigure, 'push');
            app.InterpolateandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateandPlotButtonPushed, true);
            app.InterpolateandPlotButton.Text = 'Interpolate and Plot';
            app.FitButton = uibutton(app.UIFigure, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.FindPointsButton = uibutton(app.UIFigure, 'push');
            app.FindPointsButton.ButtonPushedFcn = createCallbackFcn(app, @FindPointsButtonPushed, true);
            app.FindPointsButton.Text = 'Find Points';
            app.CellNameEditFieldLabel = uilabel(app.UIFigure);
            app.CellNameEditFieldLabel.Text = 'Cell Name';
            app.CellNum = uieditfield(app.UIFigure, 'text');
            app.PointsSliderLabel = uilabel(app.UIFigure);
            app.PointsSliderLabel.Text = 'Points';
            app.PointsSlider = uislider(app.UIFigure);
            app.PointsSlider.ValueChangedFcn = createCallbackFcn(app, @PointsSliderValueChanged, true);
            app.PointsSlider.ValueChangingFcn = createCallbackFcn(app, @PointsSliderValueChanging, true);
            app.Rise1Label = uilabel(app.UIFigure);
            app.Rise1Label.Text = 'Rise 1';
            app.rise1 = uitextarea(app.UIFigure);
            app.Rise2Label = uilabel(app.UIFigure);
            app.Rise2Label.Text = 'Rise 2';
            app.rise2 = uitextarea(app.UIFigure);
            app.Fall1Label = uilabel(app.UIFigure);
            app.Fall1Label.Text = 'Fall 1';
            app.fall1 = uitextarea(app.UIFigure);
            app.Fall2Label = uilabel(app.UIFigure);
            app.Fall2Label.Text = 'Fall 2';
            app.fall2 = uitextarea(app.UIFigure);
            app.E0TextAreaLabel = uilabel(app.UIFigure);
            app.E0TextAreaLabel.Text = 'E0';
            app.E0 = uitextarea(app.UIFigure);
            app.E1TextAreaLabel = uilabel(app.UIFigure);
            app.E1TextAreaLabel.Text = 'E1';
            app.E1 = uitextarea(app.UIFigure);
            app.Tau0TextAreaLabel = uilabel(app.UIFigure);
            app.Tau0TextAreaLabel.Text = 'Tau0';
            app.Tau0 = uitextarea(app.UIFigure);
            app.Tau1TextArea_2Label = uilabel(app.UIFigure);
            app.Tau1TextArea_2Label.Text = 'Tau1';
            app.Tau1 = uitextarea(app.UIFigure);
            app.ErrorTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorTextAreaLabel.Text = 'Error';
            app.Error = uitextarea(app.UIFigure);
            app.PercentageRecoveryTextAreaLabel = uilabel(app.UIFigure);
            app.PercentageRecoveryTextAreaLabel.Text = 'Percentage Recovery';
            app.PRB = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filterandfit2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end